classdef ControlBoard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FertilizerPumpSwitchLabel   matlab.ui.control.Label
        FertilizerPumpSwitch        matlab.ui.control.ToggleSwitch
        MotorPumpSwitchLabel        matlab.ui.control.Label
        MotorPumpSwitch             matlab.ui.control.ToggleSwitch
        EmergencyDrainSystemSwitchLabel  matlab.ui.control.Label
        EmergencyDrainSystemSwitch  matlab.ui.control.ToggleSwitch
        Lamp                        matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        ChannelId = 1360392; 
        WriteApi = "87FWY64K19D0X58W";
        ReadApi = "Z6OCAR6RU3J8HBM4";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MotorPumpSwitch
        function MotorPumpSwitchValueChanged(app, event)
            value = app.MotorPumpSwitch.Value;
            if value == "On"
                thingSpeakWrite(app.ChannelId,'Fields',5,'Values',1,'WriteKey',app.WriteApi);
            else
                thingSpeakWrite(app.ChannelId,'Fields',5,'Values',0,'WriteKey',app.WriteApi);
            end
            pause(10);
        end

        % Value changed function: FertilizerPumpSwitch
        function FertilizerPumpSwitchValueChanged(app, event)
            value = app.FertilizerPumpSwitch.Value;
            if value == "On"
                thingSpeakWrite(app.ChannelId,'Fields',6,'Values',1,'WriteKey',app.WriteApi);
                app.Lamp.Color = 'green';
            else
                thingSpeakWrite(app.ChannelId,'Fields',6,'Values',0,'WriteKey',app.WriteApi);
                app.Lamp.Color = 'white';
            end  
            pause(10);          
        end

        % Value changed function: EmergencyDrainSystemSwitch
        function EmergencyDrainSystemSwitchValueChanged(app, event)
            value = app.EmergencyDrainSystemSwitch.Value;
            if value == "On"
                thingSpeakWrite(app.ChannelId,'Fields',7,'Values',1,'WriteKey',app.WriteApi);
                app.Lamp_2.Color = 'red';
            else
                thingSpeakWrite(app.ChannelId,'Fields',7,'Values',0,'WriteKey',app.WriteApi);
                app.Lamp_2.Color = 'white';
            end
            pause(10);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FertilizerPumpSwitchLabel = uilabel(app.UIFigure);
            app.FertilizerPumpSwitchLabel.Text = 'FertilizerPump';
            app.FertilizerPumpSwitch = uiswitch(app.UIFigure, 'toggle');
            app.FertilizerPumpSwitch.ValueChangedFcn = createCallbackFcn(app, @FertilizerPumpSwitchValueChanged, true);
            app.MotorPumpSwitchLabel = uilabel(app.UIFigure);
            app.MotorPumpSwitchLabel.Text = 'MotorPump';
            app.MotorPumpSwitch = uiswitch(app.UIFigure, 'toggle');
            app.MotorPumpSwitch.ValueChangedFcn = createCallbackFcn(app, @MotorPumpSwitchValueChanged, true);
            app.EmergencyDrainSystemSwitchLabel = uilabel(app.UIFigure);
            app.EmergencyDrainSystemSwitchLabel.Text = {'Emergency'; 'Drain System'; ''};
            app.EmergencyDrainSystemSwitch = uiswitch(app.UIFigure, 'toggle');
            app.EmergencyDrainSystemSwitch.ValueChangedFcn = createCallbackFcn(app, @EmergencyDrainSystemSwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ControlBoard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end